classdef test5wired < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        MotorStopButton   matlab.ui.control.Button
        MotorStartButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
       SerialObj
        CompPort = "COM6";
        BaudRate = 115200;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          try
                app.SerialObj = serialport(app.CompPort, app.BaudRate);
                disp("Serial port opened on " + app.CompPort);
            catch ME
                uialert(app.UIFigure, ME.message, 'Serial Connection Error');
            end
        end

        % Callback function
        function MotorStartButtonPushed(app, event)
            
        end

        % Button pushed function: MotorStartButton
        function StartButtonPushed(app, event)
            try
                if isempty(app.SerialObj) || ~isvalid(app.SerialObj)
                    app.SerialObj = serialport(app.CompPort, app.BaudRate);
                end
                write(app.SerialObj, '1', "char");
                disp('Sent serial: 1 (start)');
            catch ME
                uialert(app.UIFigure, ME.message, 'Serial Connection Error');
            end
        end

        % Button pushed function: MotorStopButton
        function StopButtonPushed(app, event)
        try
                if isempty(app.SerialObj) || ~isvalid(app.SerialObj)
                    app.SerialObj = serialport(app.CompPort, app.BaudRate);
                end
                write(app.SerialObj, '0', "char");
                disp('Sent serial: 0 (stop)');
            catch ME
                uialert(app.UIFigure, ME.message, 'Serial Connection Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MotorStartButton = uibutton(app.UIFigure, 'push');
            app.MotorStartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.MotorStartButton.Text = 'Motor Start';
            app.MotorStopButton = uibutton(app.UIFigure, 'push');
            app.MotorStopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.MotorStopButton.Text = 'Motor Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test5wired

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end